classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NoofObjectsPickedEditFieldLabel  matlab.ui.control.Label
        NoofObjectsPickedEditField  matlab.ui.control.NumericEditField
        ChooseShapeDropDownLabel    matlab.ui.control.Label
        ChooseShapeDropDown         matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        ChooseColoursPanel          matlab.ui.container.Panel
        RedCheckBox                 matlab.ui.control.CheckBox
        BlueCheckBox                matlab.ui.control.CheckBox
        YellowCheckBox              matlab.ui.control.CheckBox
        ImageProcessingSwitchLabel  matlab.ui.control.Label
        ImageProcessingSwitch       matlab.ui.control.Switch
        MessageLabel                matlab.ui.control.Label
        ClearTableButton            matlab.ui.control.Button
        SubmitOrderButton           matlab.ui.control.Button
        ResetRobotButton            matlab.ui.control.Button
    end

    properties (Access = private)
        MemoryTimer        % Timer object
        count = 0;
    end

    methods (Access = private)
    
       function MemoryTimerFcn(app,~,~)
            app.count = app.count + 1;
            %fprintf("Second: %d\n", app.count);
            ImageProcessingSwitchValueChanged(app);
       end  
       
       function rosStatusCallback(app, ~, status_msg)
            %fprintf("rosStatusCallback\n");
            %status_msg.Data
            app.MessageLabel.Text = status_msg.Data;
       end
    end
    
    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all;
            %close all;
            global imSub pcSub posPub posSub orderPub statusSub moveRobotPub clearTablePub clearTableStringPub
            global red blue yellow rectangle cylinder triangle all_shapes shape_val
            global ax
            global pixel_positions msg_counter
            global ipaddress
            global resetPub
            ipaddress = '192.168.1.26';
            setenv('ROS_MASTER_URI',"http://"+ipaddress+":11311")
            
            robotType = 'Gazebo';
            rosshutdown;
            rosinit(ipaddress);
            blockposes = rossubscriber('/gazebo/link_states');
            pause(2);
            posdata = receive(blockposes,10);
            imSub = rossubscriber('/camera/color/image_raw');
            pcSub = rossubscriber('/camera/depth/image_raw');
            infoSub = rossubscriber('/camera/color/camera_info'); 
            posPub = rospublisher('/cv_pos','std_msgs/String');
            posSub = rossubscriber('/cv_pos');
            
            orderPub = rospublisher('/cv_order','std_msgs/String');
            statusSub = rossubscriber('/cv_status','std_msgs/String',@app.rosStatusCallback);
            moveRobotPub = rospublisher('/cv_move_robot','std_msgs/String');
            clearTablePub = rospublisher('/clear_table','std_msgs/Bool');
            clearTableStringPub = rospublisher('/clear_table_string','std_msgs/String');
            resetPub = rospublisher('/cv_reset','std_msgs/Bool');

            rostopic list;
            
            % id, type, x, y, z
            
            
            % pixel positions are in x,y format
            %pixel_positions = [167 87; 200 200; 400 400;];
                        
            msg_counter = 6;

            % Initially find all shapes/colours
            red = 1;
            blue = 2;
            yellow = 3;
            rectangle = 1;
            cylinder = 2;
            triangle = 3;
            all_shapes = 4;
            shape_val = all_shapes;
            
            % initialise plot
            ax = app.UIAxes;
            
             % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.5, ...                     % Period is 1 second
                'BusyMode', 'drop',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
            start(app.MemoryTimer);
        end

        % Value changed function: RedCheckBox
        function RedCheckBoxValueChanged(app, event)
            global red
            value = app.RedCheckBox.Value;
            red = value;
            disp(value);
            ImageProcessingSwitchValueChanged(app);

        end

        % Value changed function: BlueCheckBox
        function BlueCheckBoxValueChanged(app, event)
            global blue
            value = app.BlueCheckBox.Value;
            if value == 1
                blue = 2;
            else
                blue = 0;
            end
            disp(value);
            ImageProcessingSwitchValueChanged(app);
        end

        % Value changed function: YellowCheckBox
        function YellowCheckBoxValueChanged(app, event)
            global yellow
            value = app.YellowCheckBox.Value;
            if value == 1
                yellow = 3;
            else
                yellow = 0;
            end
            disp(value);
            ImageProcessingSwitchValueChanged(app);
        end

        % Value changed function: ImageProcessingSwitch
        function ImageProcessingSwitchValueChanged(app, event)
            global imSub pcSub posPub posSub orderPub statusSub
            global red yellow blue
            global ax
            global pixel_positions msg_counter dict object_count
            global shape_val
            
            show_images = false;
            
            value = app.ImageProcessingSwitch.Value;
            if value == "Off"
                cla(ax);
            end
            if value == "On"
                
             
                 %while true
                xPos = 0;
                yPos = 0;
                zPos = 0;
                
                testIm  = readImage(imSub.LatestMessage);
                if show_images == true
                    figure(1);
                    imshow(testIm);
                end
%                 imshape = size(testIm)
                
                depthIm  = readImage(pcSub.LatestMessage);
                % get location
                [xRed,yRed] = getPosColandShape(testIm, red, shape_val);
                [xBlue,yBlue] = getPosColandShape(testIm, blue, shape_val);
                [xYellow,yYellow] = getPosColandShape(testIm, yellow, shape_val);
                % combine
                xPos = cat(1,xRed,xBlue,xYellow);
                yPos = cat(1,yRed,yBlue,yYellow);
                %zPos = cat(1,zRed,zBlue,zYellow);
                
                imshow(testIm,'Parent',app.UIAxes);
                hold (app.UIAxes,'on');
                plot(app.UIAxes,xPos,yPos,'g*');
                
                
                %axis on;
                depth_max = max(max(depthIm));
                depth_min = min(min(depthIm));
                depthImDisplay = (depthIm-depth_min)/(depth_max - depth_min);
                % for grayscale image, matlab expects values from 0.0 to 1.0, not 0 to
                % 255
                if show_images == true
                    figure(2);
                    imshow(depthImDisplay);
                end
                
               
                dict = containers.Map();
                
                positions.index = -1;
                positions.type = "NA";
                gp3.x = -1;
                gp3.y = -1;
                gp3.z = -1;
                positions.gp3d = gp3;
                
                shape_str = "unknown";
                if shape_val == 1
                    shape_str = "rectangle";
                elseif shape_val == 2
                    shape_str = "cylinder";
                elseif shape_val == 3
                    shape_str = "triangle";
                end
                
                index = 1;
                if xRed(1) ~= -1
                    for i = 1:length(xRed)
                        gp3d = toGlobal(toCamera(xRed(i),yRed(i),depthIm(yRed(i),xRed(i))));
                        p.index = index;
                        p.type = "red_"+shape_str;
                        p.gp3d = gp3d;
                        positions(index) = p;
                        
                        if isKey(dict, p.type)
                            dict(p.type) = dict(p.type) + 1;
                        else
                            dict(p.type) = 1;
                        end
                        
                        index = index + 1;
                    end
                end
                if xBlue(1) ~= -1
                    for i = 1:length(xBlue)
                        gp3d = toGlobal(toCamera(xBlue(i),yBlue(i),depthIm(yBlue(i),xBlue(i))));
                        p.index = index;
                        p.type = "blue_"+shape_str;
                        p.gp3d = gp3d;
                        positions(index) = p;
                        
                        if isKey(dict, p.type)
                            dict(p.type) = dict(p.type) + 1;
                        else
                            dict(p.type) = 1;
                        end
                        index = index + 1;
                    end
                end
                if xYellow(1) ~= -1
                    for i = 1:length(xYellow)
                        gp3d = toGlobal(toCamera(xYellow(i),yYellow(i),depthIm(yYellow(i),xYellow(i))));
                        p.index = index;
                        p.type = "yellow_"+shape_str;
                        p.gp3d = gp3d;
                        positions(index) = p;
                        
                        if isKey(dict, p.type)
                            dict(p.type) = dict(p.type) + 1;
                        else
                            dict(p.type) = 1;
                        end
                        index = index + 1;
                    end
                end
                %positions

                object_count = index - 1;
                cv_msg = rosmessage(posPub);
                
                if positions(1).type ~= "NA"
                    cv_msg.Data = sprintf("%d|%s",msg_counter,toString(positions));
                    send(posPub,cv_msg);
                end


                %saveIms("img"+msg_counter, testIm, depthIm, depthImDisplay)
                
                msg_counter = msg_counter + 1;
                %pause(10)
            %     msg = receive(posSub,3)
                
              
            %end
            end
        end

        % Value changed function: NoofObjectsPickedEditField
        function NoofObjectsPickedEditFieldValueChanged(app, event)
            value = app.NoofObjectsPickedEditField.Value;
            ImageProcessingSwitchValueChanged(app);
        end

        % Value changed function: ChooseShapeDropDown
        function ChooseShapeDropDownValueChanged(app, event)
            global shape_val
            value = app.ChooseShapeDropDown.Value
            if value == "Rectangular Prism"
                shape_val = 1;
            elseif value == "Cylinder"
                shape_val = 2;
            elseif value == "Triangular Prism"
                    shape_val = 3;
            elseif value == "Any"
                    shape_val = 4;
            else
                shape_val = 0;
            end
            ImageProcessingSwitchValueChanged(app);
            
        end

        % Callback function
        function RobotActivatedSwitchValueChanged(app, event)
            global moveRobotPub
            value = app.RobotActivatedSwitch.Value
            cv_order_msg = rosmessage(moveRobotPub);
            cv_order_msg.Data = sprintf("%s",value);
            send(moveRobotPub,cv_order_msg);
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            global clearTablePub clearTableStringPub
            cv_clear_msg = rosmessage(clearTablePub);
            cv_clear_msg.Data = true;
            send(clearTablePub,cv_clear_msg);
            cv_clear_msg = rosmessage(clearTableStringPub);
            cv_clear_msg.Data = sprintf("Clear");
            send(clearTableStringPub,cv_clear_msg);
        end

        % Button pushed function: SubmitOrderButton
        function SubmitOrderButtonPushed(app, event)
            global dict orderPub object_count msg_counter
            numObjects = app.NoofObjectsPickedEditField.Value;
            orders_dict = containers.Map();
            disp(object_count > 1)
            disp(length(dict)>0)
            
            if numObjects > 0 && object_count >= 1 && length(dict)>0
                for i = 1:min(numObjects,object_count)
                     rr = randsample(keys(dict),1);
                     r = rr{1};
                     if isKey(orders_dict, r)
                        orders_dict(r) = orders_dict(r) + 1;
                     else
                        orders_dict(r) = 1;
                     end
                     
                     dict(r) = dict(r) - 1;
                     if dict(r) == 0
                         remove(dict,{r});
                     end
                end
                
                order_string = "";
                for key = keys(orders_dict)
                    key = key{1};
                    order_string = order_string +"|"+ key+","+orders_dict(key);
                end
                
                if order_string ~= ""
                    cv_order_msg = rosmessage(orderPub);
                    cv_order_msg.Data = sprintf("%d%s",msg_counter,order_string);
                    send(orderPub,cv_order_msg);
                end
            end
        end

        % Button pushed function: ResetRobotButton
        function ResetRobotButtonPushed(app, event)
            global resetPub
            msg = rosmessage(resetPub);
            msg.Data = true;
            send(resetPub,msg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NoofObjectsPickedEditFieldLabel = uilabel(app.UIFigure);
            app.NoofObjectsPickedEditFieldLabel.Text = 'No. of Objects Picked';
            app.NoofObjectsPickedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofObjectsPickedEditField.ValueChangedFcn = createCallbackFcn(app, @NoofObjectsPickedEditFieldValueChanged, true);
            app.NoofObjectsPickedEditField.Value = 3;
            app.ChooseShapeDropDownLabel = uilabel(app.UIFigure);
            app.ChooseShapeDropDownLabel.Text = 'Choose Shape';
            app.ChooseShapeDropDown = uidropdown(app.UIFigure);
            app.ChooseShapeDropDown.Items = {'Rectangular Prism', 'Cylinder', 'Triangular Prism', 'Any', ''};
            app.ChooseShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseShapeDropDownValueChanged, true);
            app.ChooseShapeDropDown.Value = 'Any';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Processing')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTick = [0 640];
            app.UIAxes.XTickLabel = {'0'; '640'};
            app.ChooseColoursPanel = uipanel(app.UIFigure);
            app.ChooseColoursPanel.Title = 'Choose Colours';
            app.RedCheckBox = uicheckbox(app.ChooseColoursPanel);
            app.RedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedCheckBoxValueChanged, true);
            app.RedCheckBox.Text = 'Red';
            app.RedCheckBox.Value = true;
            app.BlueCheckBox = uicheckbox(app.ChooseColoursPanel);
            app.BlueCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlueCheckBoxValueChanged, true);
            app.BlueCheckBox.Text = 'Blue';
            app.BlueCheckBox.Value = true;
            app.YellowCheckBox = uicheckbox(app.ChooseColoursPanel);
            app.YellowCheckBox.ValueChangedFcn = createCallbackFcn(app, @YellowCheckBoxValueChanged, true);
            app.YellowCheckBox.Text = 'Yellow';
            app.YellowCheckBox.Value = true;
            app.ImageProcessingSwitchLabel = uilabel(app.UIFigure);
            app.ImageProcessingSwitchLabel.Text = 'Image Processing';
            app.ImageProcessingSwitch = uiswitch(app.UIFigure, 'slider');
            app.ImageProcessingSwitch.ValueChangedFcn = createCallbackFcn(app, @ImageProcessingSwitchValueChanged, true);
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = {'Awaiting communication from robot...'; ''};
            app.ClearTableButton = uibutton(app.UIFigure, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = {'Clear Table'; ''};
            app.SubmitOrderButton = uibutton(app.UIFigure, 'push');
            app.SubmitOrderButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitOrderButtonPushed, true);
            app.SubmitOrderButton.Text = 'Submit Order';
            app.ResetRobotButton = uibutton(app.UIFigure, 'push');
            app.ResetRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRobotButtonPushed, true);
            app.ResetRobotButton.Text = 'Reset Robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end